classdef result_view_reg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        Button                matlab.ui.control.Button           % Forward
        Button2               matlab.ui.control.Button           % Back
        LabelNumericEditField matlab.ui.control.Label            % Go to
        NumericEditField      matlab.ui.control.NumericEditField % [1 Inf]
    end


    properties (Access = private)
        current     % current element
        images      % images to show
        pred        % activations of the network
        gtx         % ground truth x
        gty         % ground truth y
        lmx         % prediction x
        lmy         % prediction y
        fig
    end

    methods (Access = public)
        
        function update(app)
            show_result_reg(app.images, app.pred, app.gtx, app.gty, app.lmx, app.lmy, app.current, app.fig);
            app.NumericEditField.Value = app.current;
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fig = figure;
            app.current = 1;
            [app.images,app.pred,app.gtx,app.gty,app.lmx,app.lmy] = result_view_reg_init;
            update(app);
        end

        % Button button pushed function
        function on_button_forward(app)
            app.current = min(size(app.images,1), app.current+1);
            update(app);
        end

        % Button2 button pushed function
        function on_button_back(app)
            app.current = max(1, app.current-1);
            update(app);
        end

        % NumericEditField value changed function
        function on_goto_value(app)
            app.current = min(size(app.images,1),max(1,app.NumericEditField.Value));
            update(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @on_button_forward);
            app.Button.Text = 'Forward';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @on_button_back);
            app.Button2.Text = 'Back';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Go to';
            app.NumericEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @on_goto_value);
            app.NumericEditField.Limits = [1 Inf];
            app.NumericEditField.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = result_view_reg()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end